classdef ConvergenceTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TextArea                matlab.ui.control.TextArea
        ZoomTo                  matlab.ui.control.NumericEditField
        ZoomToEditFieldLabel    matlab.ui.control.Label
        ZoomFrom                matlab.ui.control.NumericEditField
        ZoomFromEditFieldLabel  matlab.ui.control.Label
        ExponentialButton       matlab.ui.control.Button
        UniformButton           matlab.ui.control.Button
        NormalButton            matlab.ui.control.Button
        MS                      matlab.ui.control.UIAxes
        inProb                  matlab.ui.control.UIAxes
        distribution            matlab.ui.control.UIAxes
        AS                      matlab.ui.control.UIAxes
        convergence             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ExponentialButton
        function ExponentialButtonPushed(app, event)
        a = app.ZoomFrom.Value;
        b = app.ZoomTo.Value;
        [T, count1, count2, error, count3] = conv_exponential();
        plot(app.AS,a:1:b,count1(a:1:b),'magenta')
        plot(app.inProb,a:1:1000,count2(a:1:1000),'red')
        plot(app.MS,a:1:b,error(a:1:b),'blue')
        plot(app.distribution,0:0.00001:2,count3,'r','LineWidth',2);
        plot(app.convergence,1:1:2000,T(1:500,1:1:2000))
        xlim(app.distribution,[0 2]) ;
        ylim(app.AS,[0 1.2]) ;
        ylim(app.inProb,[0 1.2]) ;
        ylim(app.MS,[0 1.2]) ;
        ylim(app.distribution,[0 1.2]) ;
        legend(app.AS,'AS Conv')
        legend(app.inProb,'Conv in Prob')
        legend(app.MS,'MS Conv')
        end

        % Callback function
        function GenerateButtonPushed(app, event)


        end

        % Button pushed function: NormalButton
        function NormalButtonPushed(app, event)
        a = app.ZoomFrom.Value;
        b = app.ZoomTo.Value;
        [T, count1, count2, error, count3] = conv_normal();
        plot(app.AS,a:1:b,count1(a:1:b),'magenta')
        plot(app.inProb,a:1:1000,count2(a:1:1000),'red')
        plot(app.MS,a:1:b,error(a:1:b),'blue')
        plot(app.distribution,0:0.00001:2,count3,'r','LineWidth',2);
        plot(app.convergence,1:1:2000,T(1:500,1:1:2000))
        xlim(app.distribution,[0 2]) ;
        ylim(app.AS,[0 1.2]) ;
        ylim(app.inProb,[0 1.2]) ;
        ylim(app.MS,[0 1.2]) ;
        ylim(app.distribution,[0 1.2]) ;
        legend(app.AS,'AS Conv')
        legend(app.inProb,'Conv in Prob')
        legend(app.MS,'MS Conv')
        end

        % Button pushed function: UniformButton
        function UniformButtonPushed(app, event)
        a = app.ZoomFrom.Value;
        b = app.ZoomTo.Value;
        [T, count1, count2, error, count3] = conv_uniform();
        plot(app.AS,a:1:b,count1(a:1:b),'magenta')
        plot(app.inProb,a:1:1000,count2(a:1:1000),'red')
        plot(app.MS,a:1:b,error(a:1:b),'blue')
        plot(app.distribution,0:0.00001:2,count3,'r','LineWidth',2);
        plot(app.convergence,1:1:2000,T(1:500,1:1:2000))
        xlim(app.distribution,[0 2]) ;
        ylim(app.AS,[0 1.2]) ;
        ylim(app.inProb,[0 1.2]) ;
        ylim(app.MS,[0 1.2]) ;
        ylim(app.distribution,[0 1.2]) ;
        legend(app.AS,'AS Conv')
        legend(app.inProb,'Conv in Prob')
        legend(app.MS,'MS Conv')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.convergence = uiaxes(app.UIFigure);
            title(app.convergence, 'Realisations of Y')
            xlabel(app.convergence, 'n')
            ylabel(app.convergence, '|X(n,w) - X(w)|')
            app.AS = uiaxes(app.UIFigure);
            title(app.AS, 'Almost Sure Convergence')
            xlabel(app.AS, 'n')
            ylabel(app.AS, 'a(n)')
            app.distribution = uiaxes(app.UIFigure);
            title(app.distribution, 'Convergence in Distribution')
            xlabel(app.distribution, 'n')
            ylabel(app.distribution, 'F(y)')
            app.inProb = uiaxes(app.UIFigure);
            title(app.inProb, 'Convergence in probability')
            xlabel(app.inProb, 'n')
            ylabel(app.inProb, 'p(n)')
            app.MS = uiaxes(app.UIFigure);
            title(app.MS, 'Convergence in Mean Square')
            xlabel(app.MS, 'n')
            ylabel(app.MS, 'en')
            app.NormalButton = uibutton(app.UIFigure, 'push');
            app.NormalButton.ButtonPushedFcn = createCallbackFcn(app, @NormalButtonPushed, true);
            app.NormalButton.Text = 'Normal';
            app.UniformButton = uibutton(app.UIFigure, 'push');
            app.UniformButton.ButtonPushedFcn = createCallbackFcn(app, @UniformButtonPushed, true);
            app.UniformButton.Text = 'Uniform';
            app.ExponentialButton = uibutton(app.UIFigure, 'push');
            app.ExponentialButton.ButtonPushedFcn = createCallbackFcn(app, @ExponentialButtonPushed, true);
            app.ExponentialButton.Text = 'Exponential';
            app.ZoomFromEditFieldLabel = uilabel(app.UIFigure);
            app.ZoomFromEditFieldLabel.Text = 'Zoom From';
            app.ZoomFrom = uieditfield(app.UIFigure, 'numeric');
            app.ZoomFrom.Limits = [1 1000];
            app.ZoomFrom.Value = 1;
            app.ZoomToEditFieldLabel = uilabel(app.UIFigure);
            app.ZoomToEditFieldLabel.Text = 'Zoom To';
            app.ZoomTo = uieditfield(app.UIFigure, 'numeric');
            app.ZoomTo.Limits = [100 2000];
            app.ZoomTo.Value = 2000;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'No. of Realizations : 500'; 'No. of samples: 2000  '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConvergenceTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end